classdef MDSIC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        LJandSIconverterPanel           matlab.ui.container.Panel
        Switch                          matlab.ui.control.RockerSwitch
        VariabletoconvertDropDownLabel  matlab.ui.control.Label
        VariabletoconvertDropDown       matlab.ui.control.DropDown
        InputValueEditFieldLabel        matlab.ui.control.Label
        InputValueEditField             matlab.ui.control.NumericEditField
        CONVERTButton                   matlab.ui.control.Button
        OutputEditFieldLabel            matlab.ui.control.Label
        OutputEditField                 matlab.ui.control.NumericEditField
        RESETButton                     matlab.ui.control.Button
        HELPButton                      matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CONVERTButton
        function CONVERTButtonPushed(app, event)
            choice = app.Switch.Value;
            variable = app.VariabletoconvertDropDown.Value;
            input = app.InputValueEditField.Value;
            
            
            sigma = 3.4e-10 ;
            epsilon = 1.65e-21 ; 
            kB = 1.380649e-23 ;
            m= 6.69e-26 ; 
            
            
            Temp_SI2MD = kB/epsilon ; 
            Time_SI2MD = (epsilon/(m*sigma^2))^0.5 ; 
            Dist_SI2MD = 1/sigma ; 
            Den_SI2MD = sigma^3 ; 
            SurfTen_SI2MD = (sigma^2) / epsilon ;
            Force_SI2MD = sigma/epsilon ;
            Prsr_SI2MD = sigma^3/epsilon ; 
            En_SI2MD = 1/epsilon ; 
            
            
            if choice == 'LJ-to_SI' 
                % conversion from LJ to SI
                switch variable 
                    case 'Temperature'
                        output = input / Temp_SI2MD ;
                    case 'Time'
                        output = input / Time_SI2MD ;
                    case 'Distance'
                        output = input / Dist_SI2MD ;
                    case 'Density'
                        output = input / Den_SI2MD ;
                    case 'SurfaceTension'
                        output = input / SurfTen_SI2MD ;
                    case 'Force'
                        output = input / Force_SI2MD ; 
                    case 'Pressure'
                        output = input / Prsr_SI2MD ; 
                    case 'Energy'
                        output = input / En_SI2MD ;
                        
                    otherwise 
                        output = 404 ; 
                end


            elseif choice == 'SI-to-LJ'
                % conversion from SI to LJ
                switch variable 
                    case 'Temperature'
                        output = input * Temp_SI2MD ;
                    case 'Time'
                        output = input * Time_SI2MD ;
                    case 'Distance'
                        output = input * Dist_SI2MD ;
                    case 'Density'
                        output = input * Den_SI2MD ;
                    case 'SurfaceTension'
                        output = input * SurfTen_SI2MD ;
                    case 'Force'
                        output = input * Force_SI2MD ; 
                    case 'Pressure'
                        output = input * Prsr_SI2MD ; 
                    case 'Energy'
                        output = input * En_SI2MD ;
                        
                    otherwise 
                        output = 404 ; 
                end

            end

            app.OutputEditField.Value = output ; 
            fprintf('%d',output)
            
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            choice = app.Switch.Value;
        end

        % Value changed function: VariabletoconvertDropDown
        function VariabletoconvertDropDownValueChanged(app, event)
            variable = app.VariabletoconvertDropDown.Value;
            
        end

        % Value changed function: InputValueEditField
        function InputValueEditFieldValueChanged(app, event)
            input = app.InputValueEditField.Value;
            
        end

        % Value changed function: OutputEditField
        function OutputEditFieldValueChanged(app, event)
            output_cnv = app.OutputEditField.Value;
            
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            clearvars ;
            close(gcbf) ; 
            MDSIC ;
        end

        % Button pushed function: HELPButton
        function HELPButtonPushed(app, event)
            msgbox('Conversion between SI and LJ units.')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LJandSIconverterPanel = uipanel(app.UIFigure);
            app.LJandSIconverterPanel.Title = 'LJ and SI converter';
            app.Switch = uiswitch(app.LJandSIconverterPanel, 'rocker');
            app.Switch.Items = {'LJ-to_SI', 'SI-to-LJ'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'LJ-to_SI';
            app.VariabletoconvertDropDownLabel = uilabel(app.LJandSIconverterPanel);
            app.VariabletoconvertDropDownLabel.Text = 'Variable to convert';
            app.VariabletoconvertDropDown = uidropdown(app.LJandSIconverterPanel);
            app.VariabletoconvertDropDown.Items = {'Temperature', 'Time', 'Distance', 'Density', 'SurfaceTension', 'Force', 'Pressure', 'Energy'};
            app.VariabletoconvertDropDown.ValueChangedFcn = createCallbackFcn(app, @VariabletoconvertDropDownValueChanged, true);
            app.VariabletoconvertDropDown.Value = 'Temperature';
            app.InputValueEditFieldLabel = uilabel(app.LJandSIconverterPanel);
            app.InputValueEditFieldLabel.Text = 'Input Value';
            app.InputValueEditField = uieditfield(app.LJandSIconverterPanel, 'numeric');
            app.InputValueEditField.ValueChangedFcn = createCallbackFcn(app, @InputValueEditFieldValueChanged, true);
            app.CONVERTButton = uibutton(app.LJandSIconverterPanel, 'push');
            app.CONVERTButton.ButtonPushedFcn = createCallbackFcn(app, @CONVERTButtonPushed, true);
            app.CONVERTButton.Text = 'CONVERT';
            app.OutputEditFieldLabel = uilabel(app.LJandSIconverterPanel);
            app.OutputEditFieldLabel.Text = 'Output';
            app.OutputEditField = uieditfield(app.LJandSIconverterPanel, 'numeric');
            app.OutputEditField.ValueDisplayFormat = '%d';
            app.OutputEditField.ValueChangedFcn = createCallbackFcn(app, @OutputEditFieldValueChanged, true);
            app.RESETButton = uibutton(app.LJandSIconverterPanel, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.HELPButton = uibutton(app.LJandSIconverterPanel, 'push');
            app.HELPButton.ButtonPushedFcn = createCallbackFcn(app, @HELPButtonPushed, true);
            app.HELPButton.Text = 'HELP';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MDSIC

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end